classdef physics_toolbox_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ProjectileMotionSimTab        matlab.ui.container.Tab
        PhysicsToolboxv10DrewVillaniLabel  matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        LaunchAngledegSlider          matlab.ui.control.RangeSlider
        LaunchAngledegSliderLabel     matlab.ui.control.Label
        InitialVelocitymsSlider       matlab.ui.control.RangeSlider
        Label                         matlab.ui.control.Label
        NumberofSimsDropDown          matlab.ui.control.DropDown
        NumberofSimsDropDownLabel     matlab.ui.control.Label
        ResultsTextArea               matlab.ui.control.TextArea
        ResultsTextAreaLabel          matlab.ui.control.Label
        IncludeAirResistanceCheckBox  matlab.ui.control.CheckBox
        InitialHeightmEditField       matlab.ui.control.NumericEditField
        InitialHeightmEditFieldLabel  matlab.ui.control.Label
        SimulateButton                matlab.ui.control.Button
        GravitationalAccelerationms2EditField  matlab.ui.control.NumericEditField
        GravitationalAccelerationms2Label  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        DataAnalysisTab               matlab.ui.container.Tab
        PhysicsToolboxv10DrewVillaniLabel_2  matlab.ui.control.Label
        editmass                      matlab.ui.control.NumericEditField
        InputmasskgLabel              matlab.ui.control.Label
        ComputeEnergyCheckBox         matlab.ui.control.CheckBox
        CalculateStatsButton          matlab.ui.control.Button
        ResultsTextArea_2             matlab.ui.control.TextArea
        ResultsTextArea_2Label        matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        LoadDataButton                matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        PlottingandVisulizationTab    matlab.ui.container.Tab
        PhysicsToolboxv10DrewVillaniLabel_3  matlab.ui.control.Label
        LoadProfileButton             matlab.ui.control.Button
        SaveProfileButton             matlab.ui.control.Button
        DisplayPlotButton             matlab.ui.control.Button
        AxesScalingDropDown           matlab.ui.control.DropDown
        AxesScalingDropDownLabel      matlab.ui.control.Label
        GridlinesCheckBox             matlab.ui.control.CheckBox
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
      LoadedSimulations
      LoadedDataTable  % loads data from inputted excel / datasheet
      LoadedData
    end
    properties (Access = private)
    SavedSimulations % save our sims
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
 % slider inputted range
    velocityRange = app.InitialVelocitymsSlider.Value;  % velocity range (min,max)
    angleRange = app.LaunchAngledegSlider.Value;      % angle range (min,max)

    % height, gravity, sims, and air resistance inputs
    y0 = app.InitialHeightmEditField.Value;
    g = app.GravitationalAccelerationms2EditField.Value;
    numSims = str2double(app.NumberofSimsDropDown.Value);
    includeAir = app.IncludeAirResistanceCheckBox.Value;

    % check inputs for validity
    if numSims < 1 || g <= 0 || velocityRange(1) < 0 || angleRange(1) < 0 || angleRange(2) >= 90
        uialert(app.UIFigure, 'Please ensure input values are valid big dawg.', 'Invalid Input, bro');
        return;
    end

    
    cla(app.UIAxes); % clear axes, then hold
    hold(app.UIAxes, 'on');

    % organization!
    velocities = linspace(velocityRange(1), velocityRange(2), numSims);
    angles = linspace(angleRange(1), angleRange(2), numSims);
    results = "";

    for i = 1:numSims
        v0_i = velocities(i);
        angle_i = angles(i);

        % convert deg to radians
        theta = deg2rad(angle_i);
        vx = v0_i * cos(theta);
        vy = v0_i * sin(theta);

        % time of flight formula with our inputs
        t_flight = (vy + sqrt(vy^2 + 2 * g * y0)) / g;
        t = linspace(0, t_flight, 300);
        % trajectory formula with inputs, include air res if inputted
        % added a small drag value
        x = vx * t;
        if includeAir
            drag = 0.01; 
            y = y0 + vy * t .* exp(-drag * t) - 0.5 * g * t.^2;
        else
            y = y0 + vy * t - 0.5 * g * t.^2;
        end
%------------------------------------------------------
        plot(app.UIAxes, x, y, 'DisplayName', sprintf('Sim %d', i));
%------------------------------------------------------
        % check max, store results
        [y_max, ~] = max(y);
        range = max(x(y >= 0));
        results = results + sprintf("Sim %d: v0=%.1f m/s, angle=%.1f° → Max H=%.2f m, Range=%.2f m\n", ...
            i, v0_i, angle_i, y_max, range);
    end
%------------------------------------------------------
    title(app.UIAxes, 'Projectile Trajectories');
    xlabel(app.UIAxes, 'Distance (m)');
    ylabel(app.UIAxes, 'Height (m)');
    legend(app.UIAxes, 'show');
    grid(app.UIAxes, 'on');
    hold(app.UIAxes, 'off');
%------------------------------------------------------
    app.ResultsTextArea.Value = results;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.InitialVelocitymsSlider.Value = [10, 20];
    app.LaunchAngledegSlider.Value = [30, 60];
    app.InitialHeightmEditField.Value = 0;
    app.GravitationalAccelerationms2EditField.Value = 9.81;
    app.NumberofSimsDropDown.Value = '1';
    app.IncludeAirResistanceCheckBox.Value = false;
    app.ResultsTextArea.Value = '';
    cla(app.UIAxes);

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
         % load data from excel or csv
    [fileName, path] = uigetfile({'*.xlsx;*.csv'}, 'Select a Data File');
    
    % exit if the user fails to select a file
    if isequal(fileName, 0)
        disp('You forgot to select a file man!');
        return;
    end
    % show the file path and file name
    disp(['File selected: ', fullfile(path, fileName)]);
    
    try
        % load files
        if contains(fileName, '.xlsx')
            % excel
            app.LoadedData = readtable(fullfile(path, fileName));
        elseif contains(fileName, '.csv')
            % csv
            app.LoadedData = readtable(fullfile(path, fileName));
        else
            disp('Incorrect file type!');
            app.ResultsTextArea.Value = {'Error: Incorrect file type. Verify .xlsx or .csv is used.'};  % show error on the ui
            return;
        end       
        % verify that valid data is loaded
        if isempty(app.LoadedData)
            disp('Data has not loaded!');
            return;
        end
        
        % show loaded data in the command window (i used this for debugging)
        disp('Data has loaded!');
        disp(app.LoadedData);
        % show results in our text area
        app.ResultsTextArea.Value = {'Data has loaded!'};
        % load data into UITable data
        app.UITable.Data = app.LoadedData;
%--------------------------------------------------------------------------------
% plot
%--------------------------------------------------------------------------------
        if all(ismember({'Time', 'Position'}, app.LoadedData.Properties.VariableNames))
            plot(app.UIAxes2, app.LoadedData.Time, app.LoadedData.Position);
            xlabel(app.UIAxes2, 'Time (sec)');
            ylabel(app.UIAxes2, 'Position (meters)');
            title(app.UIAxes2, 'Position vs Time');
        else
            app.ResultsTextArea.Value = {'Error: Data does not contain "Time" and "Position" columns.'};
            disp('Error: Data must contain "Time" and "Position" columns.');
        end
%--------------------------------------------------------------------------------
% plot energy vs time
%--------------------------------------------------------------------------------
        if all(ismember({'Time', 'Energy'}, app.LoadedData.Properties.VariableNames))
            plot(app.UIAxes4, app.LoadedData.Time, app.LoadedData.Energy);
            xlabel(app.UIAxes4, 'Time (sec)');
            ylabel(app.UIAxes4, 'Energy (Joules)');
            title(app.UIAxes4, 'Energy vs Time');
        else
            disp('Energy data was not found in the loaded file');
            app.ResultsTextArea.Value = {'Error: Energy data was not found in the loaded file.'};
        end
% catch
    catch ME
        % error loading file
        disp(['Error loading file: ', ME.message]);
        app.ResultsTextArea.Value = {['Error loading file: ', ME.message]};
    end

        end

        % Button pushed function: CalculateStatsButton
        function CalculateStatsButtonPushed(app, event)
     % check if relevant data is loaded
    if isempty(app.LoadedData)
        app.ResultsTextArea_2.Value = {'No data loaded.'};
        return;
    end
    data = app.LoadedData;    
    % check required columns
    if ~all(ismember({'Time', 'Velocity', 'Position'}, data.Properties.VariableNames))
        app.ResultsTextArea_2.Value = {'Missing Time, Velocity, or Position data.'};
        return;
    end

    time = data.Time;
    velocity = data.Velocity;
    position = data.Position;

    % compute and establish values
    maxSpeed = max(abs(velocity));
    [~, idxApex] = max(position);
    peakApexTime = time(idxApex);
    timeToTarget = time(end);
    resultLines = {
        ['Max Speed: ', num2str(maxSpeed, '%.2f'), ' m/s']
        ['Peak Apex Time: ', num2str(peakApexTime, '%.2f'), ' s']
        ['Time to Target: ', num2str(timeToTarget, '%.2f'), ' s'] };

    % compute energy if the box is checked
    if app.ComputeEnergyCheckBox.Value
        mass = app.editmass.Value; % userinputted mass
        g = 9.81;

        kineticEnergy = 0.5 * mass * velocity.^2;
        potentialEnergy = mass * g * position;
        totalEnergy = kineticEnergy + potentialEnergy;
        % energy data onto the table
        data.KineticEnergy = kineticEnergy;
        data.PotentialEnergy = potentialEnergy;
        data.TotalEnergy = totalEnergy;
        app.UITable.Data = data;
        app.LoadedData = data; % store updated data

        % plot energy graphs
%-------------------------------------------------------------------------------
        plot(app.UIAxes4, time, kineticEnergy, 'b', time, potentialEnergy, 'r', time, totalEnergy, 'k');
        legend(app.UIAxes4, {'Kinetic', 'Potential', 'Total'}, 'Location', 'best');
        xlabel(app.UIAxes4, 'Time (s)');
        ylabel(app.UIAxes4, 'Energy (J)');
        title(app.UIAxes4, 'Energy vs Time');
%-------------------------------------------------------------------------------
        % add energy values to result
        resultLines = [resultLines; {
            '--- Energy Breakdown ---'
            ['Max Kinetic Energy: ', num2str(max(kineticEnergy), '%.2f'), ' J']
            ['Max Potential Energy: ', num2str(max(potentialEnergy), '%.2f'), ' J']
            ['Max Total Energy: ', num2str(max(totalEnergy), '%.2f'), ' J']
        }];
    end

    app.ResultsTextArea_2.Value = resultLines;

        end

        % Value changed function: ComputeEnergyCheckBox
        function ComputeEnergyCheckBoxValueChanged(app, event)
    % check if data is loaded before carrying on with calculations
    if isempty(app.LoadedData)
        app.ResultsTextArea_2.Value = {'No data loaded.'};
        return;
    end

    velocity = app.LoadedData.Velocity;
    position = app.LoadedData.Position;
    time = app.LoadedData.Time;
    
    % 1.) max speed, absolute value of velocity
    maxSpeed = max(abs(velocity)); 
    
    % 2.) peak apex 
    % Find time when vy is zero or find max position
    [~, idxApex] = max(position);
    peakApexTime = time(idxApex); % apex

    % 3.) time to target (when height is 0)
    timeToTarget = time(end); 
    
    % update results TextArea with our computed stats
    app.ResultsTextArea_2.Value = {
        ['Max Speed: ', num2str(maxSpeed), ' m/s']
        ['Peak Apex Time: ', num2str(peakApexTime), ' seconds']
        ['Time to Target: ', num2str(timeToTarget), ' seconds']
    };

    % compute energy if selected
    if app.ComputeEnergyCheckBox.Value

        if all(ismember({'Position', 'Velocity', 'Time'}, app.LoadedData.Properties.VariableNames))
            % if velocity isreal
            velocity = app.LoadedData.Velocity;

   % calculations
   %----------------------------------------------------------------------------------------         
            % KE = 1/2 * m * v^2
            mass = app.editmass.Value; % user inputted mass
            kineticEnergy = 0.5 * mass * velocity.^2; % KE =

            % Assuming you want to compute Potential Energy (PE)
            % PE = m * g * h (g = gravitational constant)
            g = 9.81; % m/s^2
            potentialEnergy = mass * g * position; % PE =
   %----------------------------------------------------------------------------------------      

            % add KE and PE columns , update UI Table
            app.LoadedData.KineticEnergy = kineticEnergy;
            app.LoadedData.PotentialEnergy = potentialEnergy;            
            app.UITable.Data = app.LoadedData;

            % show that computations were completed
            app.ResultsTextArea_2.Value = [app.ResultsTextArea_2.Value; 
                {'Kinetic Energy and Potential Energy successfully computed.'}];
 
% Plot KE vs t            
%----------------------------------------------------------------------------------------    
            if all(ismember({'Time', 'KineticEnergy'}, app.LoadedData.Properties.VariableNames))
                plot(app.UIAxes4, app.LoadedData.Time, app.LoadedData.KineticEnergy);
                xlabel(app.UIAxes4, 'Time (s)');
                ylabel(app.UIAxes4, 'Kinetic Energy (J)');
                title(app.UIAxes4, 'Kinetic Energy vs Time');
            end

%----------------------------------------------------------------------------------------   

% Plot PE vs t            
%----------------------------------------------------------------------------------------    
            if all(ismember({'Time', 'PotentialEnergy'}, app.LoadedData.Properties.VariableNames))
                plot(app.UIAxes2, app.LoadedData.Time, app.LoadedData.PotentialEnergy);
                xlabel(app.UIAxes2, 'Time (s)');
                ylabel(app.UIAxes2, 'Potential Energy (J)');
                title(app.UIAxes2, 'Potential Energy vs Time');
            end
%----------------------------------------------------------------------------------------              
        else
            app.ResultsTextArea_2.Value = {'Error: Missing required columns (Position, Velocity, Time).'};
        end
    else
        % reset energy columns if checkbox isnt selected
        if ismember('KineticEnergy', app.LoadedData.Properties.VariableNames)
            app.LoadedData.KineticEnergy = []; % remove KE
            app.LoadedData.PotentialEnergy = []; % remove PE
            app.UITable.Data = app.LoadedData;
        end
        
        % update TextArea
        app.ResultsTextArea_2.Value = [app.ResultsTextArea_2.Value; 
            {'Energy computation disabled brotha man.'}];
    end
 
        end

        % Button pushed function: LoadProfileButton
        function LoadProfileButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select Simulation Profile');
    if isequal(file, 0)
        return;
    end

    loaded = load(fullfile(path, file));
    if isfield(loaded, 'SavedSimulations')
        app.SavedSimulations = loaded.SavedSimulations;
        uialert(app.UIFigure, 'Profile loaded successfully.', 'Success');
    else
        uialert(app.UIFigure, 'Selected file is missing required data.', 'Error');
    end
        end

        % Button pushed function: SaveProfileButton
        function SaveProfileButtonPushed(app, event)
            if isempty(app.SavedSimulations)
        uialert(app.UIFigure, 'No simulations to save.', 'Warning');
        return;
    end

    [file, path] = uiputfile('*.mat', 'Save Simulation Profile');
    if isequal(file, 0)
        return;
    end

    app.SavedSimulations = app.SavedSimulations; 
    save(fullfile(path, file), 'SavedSimulations');
    uialert(app.UIFigure, 'Profile saved successfully.', 'Success');

        end

        % Button pushed function: DisplayPlotButton
        function DisplayPlotButtonPushed(app, event)
            if isempty(app.SavedSimulations)
        uialert(app.UIFigure, 'No data loaded. Please load a profile.', 'Warning');
        return;
    end

    cla(app.UIAxes3);
    hold(app.UIAxes3, 'on');

    % loop
    for i = 1:length(app.SavedSimulations)
        sim = app.SavedSimulations{i};

    % plot position/ velocity vs time
    %----------------------------------------------------------------------------------------  
        plot(app.UIAxes3, sim.t, sim.y, 'DisplayName', sprintf('y Sim %d', i));
        plot(app.UIAxes3, sim.t, sim.v, '--', 'DisplayName', sprintf('v Sim %d', i));
    end

    % Configure axes
    xlabel(app.UIAxes3, 'Time (s)');
    ylabel(app.UIAxes3, 'Value');
    legend(app.UIAxes3, 'show');
    title(app.UIAxes3, 'Position and Velocity vs. Time');
    % scale axes
    scaleOption = app.AxesScalingDropDown.Value;
    if strcmp(scaleOption, 'tight')
        axis(app.UIAxes3, 'tight');
    elseif strcmp(scaleOption, 'auto')
        axis(app.UIAxes3, 'auto');
    end
    % grid
    if app.GridlinesCheckBox.Value
        grid(app.UIAxes3, 'on');
    else
        grid(app.UIAxes3, 'off');
    end
    hold(app.UIAxes3, 'off');
    % scale axes from drop down
selectedScale = app.AxesScalingDropDown.Value;

switch selectedScale
    case 'Auto'
        app.UIAxes3.XScale = 'linear';
        app.UIAxes3.YScale = 'linear';
    case 'Linear'
        app.UIAxes3.XScale = 'linear';
        app.UIAxes3.YScale = 'linear';
    case 'Logarithmic'
        app.UIAxes3.XScale = 'log';
        app.UIAxes3.YScale = 'log';
    otherwise
        app.UIAxes3.XScale = 'linear';
        app.UIAxes3.YScale = 'linear';
end

%----------------------------------------------------------------------------------------  
        end

        % Callback function: not associated with a component
        function editmassValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProjectileMotionSimTab = uitab(app.TabGroup);
            app.ProjectileMotionSimTab.Title = 'Projectile Motion Sim';
            app.UIAxes = uiaxes(app.ProjectileMotionSimTab);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GravitationalAccelerationms2Label = uilabel(app.ProjectileMotionSimTab);
            app.GravitationalAccelerationms2Label.Text = 'Gravitational Acceleration (m/s^2)';
            app.GravitationalAccelerationms2EditField = uieditfield(app.ProjectileMotionSimTab, 'numeric');
            app.SimulateButton = uibutton(app.ProjectileMotionSimTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.InitialHeightmEditFieldLabel = uilabel(app.ProjectileMotionSimTab);
            app.InitialHeightmEditFieldLabel.Text = 'Initial Height (m)';
            app.InitialHeightmEditField = uieditfield(app.ProjectileMotionSimTab, 'numeric');
            app.IncludeAirResistanceCheckBox = uicheckbox(app.ProjectileMotionSimTab);
            app.IncludeAirResistanceCheckBox.Text = 'Include Air Resistance';
            app.ResultsTextAreaLabel = uilabel(app.ProjectileMotionSimTab);
            app.ResultsTextAreaLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.ProjectileMotionSimTab);
            app.NumberofSimsDropDownLabel = uilabel(app.ProjectileMotionSimTab);
            app.NumberofSimsDropDownLabel.Text = 'Number of Sims';
            app.NumberofSimsDropDown = uidropdown(app.ProjectileMotionSimTab);
            app.NumberofSimsDropDown.Items = {'1', '2', '3', '4'};
            app.NumberofSimsDropDown.Value = '1';
            app.Label = uilabel(app.ProjectileMotionSimTab);
            app.Label.Text = 'Initial Velocity (m/s)';
            app.InitialVelocitymsSlider = uislider(app.ProjectileMotionSimTab, 'range');
            app.LaunchAngledegSliderLabel = uilabel(app.ProjectileMotionSimTab);
            app.LaunchAngledegSliderLabel.Text = 'Launch Angle (deg)';
            app.LaunchAngledegSlider = uislider(app.ProjectileMotionSimTab, 'range');
            app.ResetButton = uibutton(app.ProjectileMotionSimTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PhysicsToolboxv10DrewVillaniLabel = uilabel(app.ProjectileMotionSimTab);
            app.PhysicsToolboxv10DrewVillaniLabel.Text = {'Physics Toolbox v1.0'; 'Drew Villani'};
            app.DataAnalysisTab = uitab(app.TabGroup);
            app.DataAnalysisTab.Title = 'Data Analysis';
            app.UIAxes2 = uiaxes(app.DataAnalysisTab);
            title(app.UIAxes2, 'Energy Vs. Time')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.DataAnalysisTab);
            title(app.UIAxes4, 'Position Vs. Time')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.LoadDataButton = uibutton(app.DataAnalysisTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.UITable = uitable(app.DataAnalysisTab);
            app.UITable.ColumnName = {'Time'; 'Position'; 'Velocity'; 'Energy'};
            app.UITable.RowName = {};
            app.ResultsTextArea_2Label = uilabel(app.DataAnalysisTab);
            app.ResultsTextArea_2Label.Text = 'Results';
            app.ResultsTextArea_2 = uitextarea(app.DataAnalysisTab);
            app.CalculateStatsButton = uibutton(app.DataAnalysisTab, 'push');
            app.CalculateStatsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateStatsButtonPushed, true);
            app.CalculateStatsButton.Text = 'Calculate Stats';
            app.ComputeEnergyCheckBox = uicheckbox(app.DataAnalysisTab);
            app.ComputeEnergyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ComputeEnergyCheckBoxValueChanged, true);
            app.ComputeEnergyCheckBox.Text = 'Compute Energy';
            app.InputmasskgLabel = uilabel(app.DataAnalysisTab);
            app.InputmasskgLabel.Text = 'Input mass (kg):';
            app.editmass = uieditfield(app.DataAnalysisTab, 'numeric');
            app.editmass.Value = 1;
            app.PhysicsToolboxv10DrewVillaniLabel_2 = uilabel(app.DataAnalysisTab);
            app.PhysicsToolboxv10DrewVillaniLabel_2.Text = {'Physics Toolbox v1.0'; 'Drew Villani'};
            app.PlottingandVisulizationTab = uitab(app.TabGroup);
            app.PlottingandVisulizationTab.Title = 'Plotting and Visulization';
            app.UIAxes3 = uiaxes(app.PlottingandVisulizationTab);
            title(app.UIAxes3, 'Multi-Projectile Comparision')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.GridlinesCheckBox = uicheckbox(app.PlottingandVisulizationTab);
            app.GridlinesCheckBox.Text = 'Gridlines';
            app.AxesScalingDropDownLabel = uilabel(app.PlottingandVisulizationTab);
            app.AxesScalingDropDownLabel.Text = 'Axes Scaling';
            app.AxesScalingDropDown = uidropdown(app.PlottingandVisulizationTab);
            app.AxesScalingDropDown.Items = {'Auto', 'Linear', 'Logarithmic'};
            app.AxesScalingDropDown.Value = 'Auto';
            app.DisplayPlotButton = uibutton(app.PlottingandVisulizationTab, 'push');
            app.DisplayPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPlotButtonPushed, true);
            app.DisplayPlotButton.Text = 'Display Plot';
            app.SaveProfileButton = uibutton(app.PlottingandVisulizationTab, 'push');
            app.SaveProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButtonPushed, true);
            app.SaveProfileButton.Text = 'Save Profile';
            app.LoadProfileButton = uibutton(app.PlottingandVisulizationTab, 'push');
            app.LoadProfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProfileButtonPushed, true);
            app.LoadProfileButton.Text = 'Load Profile';
            app.PhysicsToolboxv10DrewVillaniLabel_3 = uilabel(app.PlottingandVisulizationTab);
            app.PhysicsToolboxv10DrewVillaniLabel_3.Text = {'Physics Toolbox v1.0'; 'Drew Villani'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = physics_toolbox_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end