classdef function_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        plot                      matlab.ui.control.Button
        endx                      matlab.ui.control.NumericEditField
        EndxEditFieldLabel        matlab.ui.control.Label
        startx                    matlab.ui.control.NumericEditField
        StartxEditFieldLabel      matlab.ui.control.Label
        expression                matlab.ui.control.EditField
        ExpressionEditFieldLabel  matlab.ui.control.Label
        axes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plot
        function plotButtonPushed(app, event)
            try


% input and parameters           
                % user input
                    exprStr = app.expression.Value;
                    xStart = app.startx.Value;
                    xEnd = app.endx.Value;

                    % symbol used, convert string, and check if x is used
                    syms x
                    expr = str2sym(exprStr);

                    % clear previous plotted data
                    cla(app.axes);

                    % plot
                    fplot(app.axes, expr, [xStart, xEnd], 'LineWidth', 3);

% visuals                
                    % labels for our axis
                    xlabel(app.axes, 'x');
                    ylabel(app.axes, 'f(x)');
                    title(app.axes, ['Plot of: ', char(expr)]);

% catch for final display
            catch ME
                uialert(app.UIFigure, ...
                    'Invalid expression', ...
                    'Check your input dude');

% catch (errors when troubleshooting)
          %  catch ME
          %      disp(ME.message);
          %      rethrow(ME);
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Title')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.ExpressionEditFieldLabel = uilabel(app.UIFigure);
            app.ExpressionEditFieldLabel.Text = 'Expression';
            app.expression = uieditfield(app.UIFigure, 'text');
            app.StartxEditFieldLabel = uilabel(app.UIFigure);
            app.StartxEditFieldLabel.Text = 'Start  x';
            app.startx = uieditfield(app.UIFigure, 'numeric');
            app.EndxEditFieldLabel = uilabel(app.UIFigure);
            app.EndxEditFieldLabel.Text = 'End x';
            app.endx = uieditfield(app.UIFigure, 'numeric');
            app.plot = uibutton(app.UIFigure, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = function_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end