classdef symbolic_diff_integrate_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        endingx                   matlab.ui.control.NumericEditField
        EndingxEditFieldLabel     matlab.ui.control.Label
        plot                      matlab.ui.control.Button
        startingx                 matlab.ui.control.NumericEditField
        StartingxEditFieldLabel   matlab.ui.control.Label
        result                    matlab.ui.control.Label
        variable                  matlab.ui.control.DropDown
        VariableLabel             matlab.ui.control.Label
        operations                matlab.ui.container.ButtonGroup
        IntegrateButton           matlab.ui.control.RadioButton
        DifferentiateButton       matlab.ui.control.RadioButton
        expression                matlab.ui.control.EditField
        ExpressionEditFieldLabel  matlab.ui.control.Label
        axes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plot
        function plotButtonPushed(app, event)
            try
                % expression input and variable
                exprStr = app.expression.Value;
                varStr = app.variable.Value;
                opType = app.operations.SelectedObject.Text;
                xStart = app.startingx.Value;
                xEnd = app.endingx.Value;

                % input check
                if xStart >= xEnd
                    error ('Range start >= end, fix it buddy.');
                end

                % convert str to sym
                expr = str2sym(exprStr);
                symVar = sym(varStr);

                % operations
                switch opType
                    case 'Differentiate'
                        resultExpr = diff(expr, symVar);
                    case 'Integrate'
                        resultExpr = int(expr, symVar);
                    otherwise
                        error('Invalid operation, pal.');
                end

                % display
                app.result.Text = ['Result: ', char(resultExpr)];

                % plot the functionz
                fplot(app.axes, matlabFunction(expr, 'vars', symVar), [xStart xEnd], 'r--', 'LineWidth', 1);
                hold(app.axes,"on");
                fplot(app.axes, matlabFunction(resultExpr, 'vars', symVar), [xStart xEnd], 'b', 'LineWidth', 1);
                hold(app.axes, "off");
                legend(app.axes, varStr);
                xlabel(app.axes, varStr);
                ylabel(app.axes, 'f(x)');
                title(app.axes, 'Symbolic Operation Plot');
                grid(app.axes, "on");

            catch ME

 %%% debugging catch %%%
 
                %   disp(ME.message);
                %   rethrow(ME);
                               
                uialert(app.UIFigure, ...
                    'Invalid expression and/or variable, check it dude', ...
                    'Expression Error!');
                disp(ME.message); % debugging disp
            end
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Title')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.ExpressionEditFieldLabel = uilabel(app.UIFigure);
            app.ExpressionEditFieldLabel.Text = 'Expression';
            app.expression = uieditfield(app.UIFigure, 'text');
            app.operations = uibuttongroup(app.UIFigure);
            app.operations.Title = 'Operation';
            app.DifferentiateButton = uiradiobutton(app.operations);
            app.DifferentiateButton.Text = 'Differentiate';
            app.DifferentiateButton.Value = true;
            app.IntegrateButton = uiradiobutton(app.operations);
            app.IntegrateButton.Text = 'Integrate';
            app.VariableLabel = uilabel(app.UIFigure);
            app.VariableLabel.Text = 'Variable';
            app.variable = uidropdown(app.UIFigure);
            app.variable.Items = {'x', 'y'};
            app.variable.Value = 'x';
            app.result = uilabel(app.UIFigure);
            app.result.Text = '';
            app.StartingxEditFieldLabel = uilabel(app.UIFigure);
            app.StartingxEditFieldLabel.Text = 'Starting x';
            app.startingx = uieditfield(app.UIFigure, 'numeric');
            app.plot = uibutton(app.UIFigure, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plot.Text = 'Plot';
            app.EndingxEditFieldLabel = uilabel(app.UIFigure);
            app.EndingxEditFieldLabel.Text = 'Ending x';
            app.endingx = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = symbolic_diff_integrate_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end