classdef unit_convertor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        result          matlab.ui.control.Label
        convert         matlab.ui.control.Button
        edit_value      matlab.ui.control.NumericEditField
        ValueLabel      matlab.ui.control.Label
        to_dd           matlab.ui.control.DropDown
        GotoLabel       matlab.ui.control.Label
        from_dd         matlab.ui.control.DropDown
        StartfromLabel  matlab.ui.control.Label
        category_dd     matlab.ui.control.DropDown
        CategoryLabel   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
          function result = convertUnits(~, val, category, fromUnit, toUnit)
             switch category
                case 'Length'
                    toMeters = struct('meter',1,'kilometer',1000,'inch',0.0254,'foot',0.3048,'mile',1609.344);
                    valInMeters = val * toMeters.(fromUnit);
                    result = valInMeters / toMeters.(toUnit);

                case 'Weight'
                    toGrams = struct('gram',1,'kilogram',1000,'pound',453.59237,'ounce',28.3495);
                    valInGrams = val * toGrams.(fromUnit);
                    result = valInGrams / toGrams.(toUnit);

                case 'Temperature'
                    switch fromUnit

                        case 'Celsius'
                            if strcmp(toUnit, 'Fahrenheit')
                            result = (val * 9/5) + 32;
                            elseif strcmp(toUnit, 'Kelvin')
                            result = val + 273.15;
                            else
                            result = val;
                            end
                        case 'Fahrenheit'
                            if strcmp(toUnit, 'Celsius')
                                result = (val - 32) * 5/9;
                            elseif strcmp(toUnit, 'Kelvin')
                                result = ((val - 32) * 5/9) + 273.15;
                            else
                                result = val;
                            end
                        case 'Kelvin'
                            if strcmp(toUnit, 'Celsius')
                                result = val - 273.15;
                            elseif strcmp(toUnit, 'Fahrenheit')
                                result = ((val - 273.15) * 9/5) + 32;
                            else
                                result = val;
                            end
                        otherwise
                            error('Unsupported temperature unit');
                    end
            otherwise
                error('Unsupported category.');
             end
          end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: category_dd
        function category_ddValueChanged(app, event)
            selectedCategory = app.category_dd.Value;
% update to & from dropdowns based on category
            switch selectedCategory
                case 'Length'
                    units = {'meter', 'kilometer', 'inch', 'foot', 'mile'};
                case 'Temperature'
                    units = {'Celsius', 'Fahrenheit', 'Kelvin'};
                case 'Weight'
                    units = {'gram', 'kilogram', 'pound', 'ounce'};

            end
            app.from_dd.Items = units;
            app.to_dd.Items = units;
        end

        % Button pushed function: convert
        function convertButtonPushed(app, event)
 % take our values and convert em          
            val = app.edit_value.Value;
            category = app.category_dd.Value;
            fromUnit = app.from_dd.Value;
            toUnit = app.to_dd.Value;
            try
                convertedValue = convertUnits(app, val, category, fromUnit, toUnit);
        app.result.Text = sprintf("%.4f %s", convertedValue, toUnit); % used AI for sprintf
         % catch for conversion/inputs
            catch
                                
                uialert(app.UIFigure, ...
                    'Conversion failed, broh. Check your inputs/unit', ...
                    '>>Conversion issue!<<');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CategoryLabel = uilabel(app.UIFigure);
            app.CategoryLabel.Text = 'Category:';
            app.category_dd = uidropdown(app.UIFigure);
            app.category_dd.Items = {'Length', 'Temperature', 'Weight'};
            app.category_dd.ValueChangedFcn = createCallbackFcn(app, @category_ddValueChanged, true);
            app.category_dd.Value = 'Length';
            app.StartfromLabel = uilabel(app.UIFigure);
            app.StartfromLabel.Text = 'From';
            app.from_dd = uidropdown(app.UIFigure);
            app.GotoLabel = uilabel(app.UIFigure);
            app.GotoLabel.Text = 'To';
            app.to_dd = uidropdown(app.UIFigure);
            app.ValueLabel = uilabel(app.UIFigure);
            app.ValueLabel.Text = 'Value';
            app.edit_value = uieditfield(app.UIFigure, 'numeric');
            app.convert = uibutton(app.UIFigure, 'push');
            app.convert.ButtonPushedFcn = createCallbackFcn(app, @convertButtonPushed, true);
            app.convert.Text = 'Convert';
            app.result = uilabel(app.UIFigure);
            app.result.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = unit_convertor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end