classdef PID_line_follow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SeedEditField           matlab.ui.control.NumericEditField
        SeedEditFieldLabel      matlab.ui.control.Label
        SetpointEditField       matlab.ui.control.NumericEditField
        SetpointEditFieldLabel  matlab.ui.control.Label
        kDEditField             matlab.ui.control.NumericEditField
        kDEditFieldLabel        matlab.ui.control.Label
        kIEditField             matlab.ui.control.NumericEditField
        kIEditFieldLabel        matlab.ui.control.Label
        kPEditField             matlab.ui.control.NumericEditField
        kPEditFieldLabel        matlab.ui.control.Label
        RunSimButton            matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PreviousRun = struct('t', [], 'deviation', [], 'leftSpeed', [], 'rightSpeed', []);
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)

   kP = app.kPEditField.Value;
kI = app.kIEditField.Value;
kD = app.kDEditField.Value;
setpoint = app.SetpointEditField.Value;
seed = app.SeedEditField.Value;

[t, sensorReading, leftSpeed, rightSpeed, ~] = simulateLineFollowerPololu(kP, kI, kD, setpoint, seed);

deviation = setpoint - sensorReading;

cla(app.UIAxes);
cla(app.UIAxes2);

% plot previous run
%-----------------------------------------------------
if ~isempty(app.PreviousRun.t)
    plot(app.UIAxes, app.PreviousRun.t, app.PreviousRun.deviation, '--k');
    hold(app.UIAxes, 'on');

    plot(app.UIAxes2, app.PreviousRun.t, app.PreviousRun.leftSpeed, '--r');
    plot(app.UIAxes2, app.PreviousRun.t, app.PreviousRun.rightSpeed, '--b');
    hold(app.UIAxes2, 'on');
else
    hold(app.UIAxes, 'on');
    hold(app.UIAxes2, 'on');
end

% plot run
%-----------------------------------------------------
plot(app.UIAxes, t, deviation, 'k', 'LineWidth', 1.5);
title(app.UIAxes, 'Sensor Deviation');
xlabel(app.UIAxes, 'Time (s)');
ylabel(app.UIAxes, 'Deviation');

plot(app.UIAxes2, t, leftSpeed, 'r', 'LineWidth', 1.5);
plot(app.UIAxes2, t, rightSpeed, 'b', 'LineWidth', 1.5);
title(app.UIAxes2, 'Motor Speeds');
xlabel(app.UIAxes2, 'Time (s)');
ylabel(app.UIAxes2, 'Speed');

app.PreviousRun = struct('t', t, ...
                         'deviation', deviation, ...
                         'leftSpeed', leftSpeed, ...
                         'rightSpeed', rightSpeed);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Motor Speeds Over Time')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Sensor Deviation Over Time')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RunSimButton = uibutton(app.UIFigure, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'Run Sim';
            app.kPEditFieldLabel = uilabel(app.UIFigure);
            app.kPEditFieldLabel.Text = 'kP';
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kIEditFieldLabel = uilabel(app.UIFigure);
            app.kIEditFieldLabel.Text = 'kI';
            app.kIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kDEditFieldLabel = uilabel(app.UIFigure);
            app.kDEditFieldLabel.Text = 'kD';
            app.kDEditField = uieditfield(app.UIFigure, 'numeric');
            app.SetpointEditFieldLabel = uilabel(app.UIFigure);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeedEditFieldLabel = uilabel(app.UIFigure);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_line_follow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end