classdef spring_sim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DesiredsetpointpositionEditField  matlab.ui.control.NumericEditField
        DesiredsetpointpositionEditFieldLabel  matlab.ui.control.Label
        kDEditField                  matlab.ui.control.NumericEditField
        kDEditFieldLabel             matlab.ui.control.Label
        kIEditField                  matlab.ui.control.NumericEditField
        kIEditFieldLabel             matlab.ui.control.Label
        kPEditField                  matlab.ui.control.NumericEditField
        kPEditFieldLabel             matlab.ui.control.Label
        SpringconstantkNmEditField   matlab.ui.control.NumericEditField
        SpringconstantkNmEditFieldLabel  matlab.ui.control.Label
        DampingcoefficientcNsmEditField  matlab.ui.control.NumericEditField
        DampingcoefficientcNsmLabel  matlab.ui.control.Label
        MassmkgEditField             matlab.ui.control.NumericEditField
        MassmkgEditFieldLabel        matlab.ui.control.Label
        RunSimButton                 matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lastTime      % Time vector from the previous run
    lastPosition  % Position vector from the previous run
    end
    
    methods (Access = private)
        
           function [time, position] = simulatePIDControl(app, m, c, k, kP, kI, kD, setpoint)
        dt = 0.01;
        tEnd = 10;
        time = 0:dt:tEnd;
        position = zeros(size(time));
        velocity = 0;
        errorSum = 0;
        previousError = 0;

        for i = 2:length(time)
            error = setpoint - position(i-1);
            errorSum = errorSum + error * dt;
            errorRate = (error - previousError) / dt;

            controlSignal = kP * error + kI * errorSum + kD * errorRate;

            acceleration = (1/m) * (controlSignal - c * velocity - k * position(i-1));
            velocity = velocity + acceleration * dt;
            position(i) = position(i-1) + velocity * dt;

            previousError = error;
        end
    end

end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimButton
        function RunSimButtonPushed(app, event)

    % get inputs
    m = app.MassmkgEditField.Value;
    c = app.DampingcoefficientcNsmEditField.Value;
    k = app.SpringconstantkNmEditField.Value;
    kP = app.kPEditField.Value;
    kI = app.kIEditField.Value;
    kD = app.kDEditField.Value;
    setpoint = app.DesiredsetpointpositionEditField.Value;

    % simulate pid control
    [time, position] = simulatePIDControl(app, m, c, k, kP, kI, kD, setpoint);

    % clear and hold axes
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    legendEntries = {};  % build legend

    % Plot previous run if it exists
    if ~isempty(app.lastTime)
        plot(app.UIAxes, app.lastTime, app.lastPosition, 'r--', 'LineWidth', 1.5);
        legendEntries{end+1} = 'Previous Run';
    end

    % plot run
    %---------------------------------------------------------
    plot(app.UIAxes, time, position, 'b-', 'LineWidth', 2);
    legendEntries{end+1} = 'Current Run';
    % apply built legend
    legend(app.UIAxes, legendEntries, 'Location', 'best');
    % Label and title
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Position (m)');
    title(app.UIAxes, 'PID Spring-Damping Response');
    hold(app.UIAxes, 'off');
    %---------------------------------------------------------
    % Store current run as last run
    app.lastTime = time;
    app.lastPosition = position;
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spring Sim')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunSimButton = uibutton(app.UIFigure, 'push');
            app.RunSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimButtonPushed, true);
            app.RunSimButton.Text = 'Run Sim';
            app.MassmkgEditFieldLabel = uilabel(app.UIFigure);
            app.MassmkgEditFieldLabel.Text = 'Mass m (kg)';
            app.MassmkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.DampingcoefficientcNsmLabel = uilabel(app.UIFigure);
            app.DampingcoefficientcNsmLabel.Text = 'Damping coefficient c (N·s/m)';
            app.DampingcoefficientcNsmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpringconstantkNmEditFieldLabel = uilabel(app.UIFigure);
            app.SpringconstantkNmEditFieldLabel.Text = 'Spring constant k (N/m)';
            app.SpringconstantkNmEditField = uieditfield(app.UIFigure, 'numeric');
            app.kPEditFieldLabel = uilabel(app.UIFigure);
            app.kPEditFieldLabel.Text = 'kP';
            app.kPEditField = uieditfield(app.UIFigure, 'numeric');
            app.kIEditFieldLabel = uilabel(app.UIFigure);
            app.kIEditFieldLabel.Text = 'kI';
            app.kIEditField = uieditfield(app.UIFigure, 'numeric');
            app.kDEditFieldLabel = uilabel(app.UIFigure);
            app.kDEditFieldLabel.Text = 'kD';
            app.kDEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredsetpointpositionEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredsetpointpositionEditFieldLabel.Text = 'Desired setpoint (position)';
            app.DesiredsetpointpositionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spring_sim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end